classdef Image_Degradation_Tool_l < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GlareIntensityEditField    matlab.ui.control.NumericEditField
        GlareIntensityLabel        matlab.ui.control.Label
        SelectNoiseCurvePanel      matlab.ui.container.Panel
        TickAllCheckBox            matlab.ui.control.CheckBox
        VGCheckBox                 matlab.ui.control.CheckBox
        QECheckBox                 matlab.ui.control.CheckBox
        PTCCheckBox                matlab.ui.control.CheckBox
        MTFCheckBox                matlab.ui.control.CheckBox
        ApplyButton                matlab.ui.control.Button
        NoiseDropDown              matlab.ui.control.DropDown
        BrowseButton               matlab.ui.control.Button
        NoiseDropDownLabel         matlab.ui.control.Label
        BrowseButton_2             matlab.ui.control.Button
        ImageDegradationToolLabel  matlab.ui.control.Label
        SaveButton                 matlab.ui.control.Button
        DestinationEditField       matlab.ui.control.EditField
        DestinationEditFieldLabel  matlab.ui.control.Label
        SourceEditField            matlab.ui.control.EditField
        SourceEditFieldLabel       matlab.ui.control.Label
        UIAxes_6                   matlab.ui.control.UIAxes
        UIAxes_5                   matlab.ui.control.UIAxes
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    methods (Access = private)

        %function 

        function filterDropdownList(app, changingValue, originalList)
            List = [];
            disp(changingValue)
            if ~isempty(changingValue)
                for c = 1:length(originalList)
                    if contains(originalList{c},changingValue, "IgnoreCase",true)
                        List = [List,originalList(c)];
                    end
                end
            end
            
            if ~isempty(List) % Something is found
                app.NoiseDropDown.Items = List;
            elseif ~isempty(changingValue) && isempty(List) % Nothing is found
                app.NoiseDropDown.Items = {};
            else % Restore otherwise
                app.NoiseDropDown.Items = originalList;
                if isempty(app.NoiseDropDown.ItemsData)
                    app.NoiseDropDown.Value = originalList{1};
                else
                    if isnumeric(app.DropDown.ItemsData)
                        app.NoiseDropDown.Value = app.NoiseDropDown.ItemsData(1);
                    else
                        app.NoiseDropDown.Value = app.NoiseDropDown.ItemsData{1};
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            src = app.SourceEditField.Value;
            dst = app.DestinationEditField.Value;
            persistent img;
            persistent vg;
            persistent qeImg;
            persistent ptcImg;
            files=dir(src);
            % Create a CSV file to append the data
            csvFileName = 'error_log.csv';
            fid = fopen(csvFileName, 'w');
            fprintf(fid, 'File Name,Status Message\n');
            f = waitbar(0, "Accessing the files...");
            tic;
            
            for k=1:length(files)
                kk = k-2;
                n = length(files)-2;
                f = waitbar(0/length(files), f, "Processing..."+kk+"/"+n);
                try
                    File = files(k).name;
                    %disp(File);
                    
                    if(contains(File,'.png') || contains(File,'.jpg') ||  contains(File,'.jpeg') ||  contains(File,'.jfif'))
                        img = imread(fullfile(src,File));
                        %img = rawread(fullfile(src,File));


                        if(app.NoiseDropDown.Value == "Gaussian")
                            % Adding Gaussian noise
                            degradedImg = imnoise(img,'gaussian',0.1, 0.05);
                        elseif(app.NoiseDropDown.Value == "Salt & Pepper")
                            % Adding salt & pepper noise
                            degradedImg = imnoise(img,'salt & pepper');
                        elseif(app.NoiseDropDown.Value == "poisson")
                            % Adding salt & pepper noise
                            degradedImg = imnoise(img,'poisson');
                        else
                            degradedImg = img;
                        end
                  

                        mtf_value = app.MTFCheckBox.Value;
                        %fprintf("MTF =%d\n",mtf_value);
    
                        ptc_value = app.PTCCheckBox.Value;
                        %fprintf("PTC =%d\n",ptc_value);
    
                        qe_value = app.QECheckBox.Value;
                        %fprintf("QE =%d\n",qe_value);
    
                        vg_value = app.VGCheckBox.Value;
                        %fprintf("VG =%d\n",vg_value);
    
                        % if(mtf_value==1 && ptc_value == 1 && qe_value==1 && vg_value==1)
                        tick_all_value = app.TickAllCheckBox.Value;
                        %fprintf("TickAll =%d",tick_all_value);


                        File2 = files(k).name;
                  

                        % File exists, open it in append mode
                        fid = fopen(csvFileName, 'a');
    
                        % Write the file name and error message to the CSV file
                        fprintf(fid, '"%s","Successful"\n', File2);


                        % Close the CSV file
                        fclose(fid);
                    
                          if(mtf_value==1)
                            filename = 'example_mtf.xlsx';
                            sheet = 1;
                            mtfdata = xlsread(filename,sheet);
                            %frequency = mtfdata(:, 1);
                            mtf = mtfdata(:, 2);
                            psf1D = ifft(mtf);
                            psf1D = real(psf1D);
                            psf1D = psf1D.';
                            psf = psf1D(2:end);
                            psf2D = [psf(1:33) psf1D(1) psf(33:end)];
                            psf2D = ftrans2(psf2D);
                            degradedImg = imfilter(img, psf2D, 'conv', 'replicate');
                            imshow(degradedImg, 'parent', app.UIAxes_3);
                          end

                          if(vg_value==1)
                            originalImage = degradedImg;
                            % Convert the original image to double for processing
                            original_image = double(originalImage);
                            
                            % Preprocess the image
                            blurred_image = imgaussfilt(original_image, 0.1);
                            
                            % Thresholding
                            binary_image = imbinarize(blurred_image);
                            
                            % Use regionprops to detect candidate regions (blobs)
                            stats = regionprops(binary_image, 'Centroid', 'Area');
                            
                            % Find the region with the largest area (assuming the light source is the brightest and largest blob)
                            [~, idx] = max([stats.Area]);
                            light_source = stats(idx).Centroid;
                            
                            % Define GSF parameters
                            x0 = light_source(1);
                            y0 = light_source(2);
                            sigma = 50;
                            num_layers = round(sigma*2);
                            
                            % Pad the image with zeros on all sides
                            pad_width_x = max(size(original_image, 2)/2 , num_layers);
                            pad_width_y = max(size(original_image, 1) /2, num_layers);
                            pad_width_x =round(pad_width_x);
                            pad_width_y =round(pad_width_y);
                            padded_image = padarray(original_image, [pad_width_y, pad_width_x], 0, 'both');
                            
                            % Plot the GSF
                            [x, y] = meshgrid(1:size(padded_image, 2), 1:size(padded_image, 1));
                            GSF = exp(-((x - x0).^2 + (y - y0).^2) / (2 * sigma^2));
                            
                            % Normalize the GSF
                            GSF_normalized = GSF / sum(GSF(:));
                            % Normalize the kernel volume to 1
                            GSF_normalized = GSF_normalized / sum(GSF_normalized(:).^1);
                            
                            % Convert images to frequency domain using FFT
                            fft_original = fft2(padded_image);
                            fft_GSF = fft2(ifftshift(GSF_normalized), size(padded_image, 1), size(padded_image, 2));
                            
                            % Perform element-wise multiplication in the frequency domain
                            fft_result = fft_original .* fft_GSF;
                            
                            % Convert back to the spatial domain using inverse FFT
                            result_image = ifft2(fft_result);
                            
                            % Clip pixel values to the valid range (0 to 255 for an 8-bit image)
                            result_image = max(0, min(255, result_image));
                            
                            % Crop the result image to the original size
                            output_image = result_image(1:size(original_image, 1), 1:size(original_image, 2));
                            
                            % Set the glare intensity (scale factor)
                            glare_intensity = app.GlareIntensityEditField.Value;
                            
                            % Add the glare layer to the original image
                            output_image = original_image + output_image * glare_intensity;
                            
                            % Clip pixel values to the valid range (0 to 255 for an 8-bit image)
                            output_image = max(0, min(255, output_image));

                            
                            % Convert back to uint8 for display
                            imageWithVeilingGlare = uint8(output_image);
                            vg = imageWithVeilingGlare;
                            imshow(imageWithVeilingGlare, 'parent', app.UIAxes_6);
                          
                          end

                          if(qe_value==1)
                            redChannel = imageWithVeilingGlare(:,:,1) .* 0.6;
                            greenChannel = imageWithVeilingGlare(:,:,2) .* 0.4;
                            blueChannel = imageWithVeilingGlare(:,:,3) .* 0.5;
                            imageWithVeilingGlare = cat(3, redChannel, greenChannel, blueChannel);
                            qeImg = imageWithVeilingGlare;
                            imshow(imageWithVeilingGlare, 'parent', app.UIAxes_4);
                          end

                          if(ptc_value==1)
                                filename = 'PTC.csv';
                                %sheet = 1;
                                ptcdata = xlsread(filename, 1);
                                pixel = ptcdata(:, 1);
                                std_val = ptcdata(:, 2);
                                [h, w, t] = size(imageWithVeilingGlare);
                                for i=1:h
                                    for j=1:w
                                        pixelValue = imageWithVeilingGlare(i, j, 1);
                                        n = std_val(pixelValue/8 + 1);
                                        noiseValue = normrnd(0, n);
                                        imageWithVeilingGlare(i, j, 1) = pixelValue + noiseValue; 
                                    end
                                end
                                
                                for i=1:h
                                    for j=1:w
                                        pixelValue = imageWithVeilingGlare(i, j, 2);
                                        n = std_val(pixelValue/8 + 1);
                                        noiseValue = normrnd(0, n);
                                        imageWithVeilingGlare(i, j, 2) = pixelValue + noiseValue; 
                                    end
                                end
                                
                                for i=1:h
                                    for j=1:w
                                        pixelValue = imageWithVeilingGlare(i, j, 3);
                                        n = std_val(pixelValue/8 + 1);
                                        noiseValue = normrnd(0, n);
                                        imageWithVeilingGlare(i, j, 3) = pixelValue + noiseValue; 
                                    end
                                end
                                ptcImg = imageWithVeilingGlare;
                                imshow(imageWithVeilingGlare, 'parent', app.UIAxes_5);
                            
                          end


                        imshow(imageWithVeilingGlare, 'parent', app.UIAxes_2);
                        imshow(img, 'parent', app.UIAxes);
                        imwrite(imageWithVeilingGlare, fullfile(dst,File));
                        %disp(k, '\n');
                       f = waitbar(1/1, f, "Processing..."+"1"+"/"+"1");
                    end
               catch E
                    % Get the file name and error message
                    File = files(k).name;
                    ErrorMessage = E.message;

                    % File exists, open it in append mode
                    fid = fopen(csvFileName, 'a');

                    % Write the file name and error message to the CSV file
                    fprintf(fid, '"%s","%s"\n', File, ErrorMessage);
                
                    % Close the CSV file
                    fclose(fid);
                
                    % Print the error message in MATLAB command window
                    %fprintf('Error for file %s: %s\n', File, ErrorMessage);
                end
        
            end
                f = waitbar(1,f, "Processing Done.")  
                elapsed_time = toc;
                disp(['Elapsed Time: ',num2str(elapsed_time),' seconds']);
                imshow(vg, 'parent', app.UIAxes_6);
                imshow(qeImg, 'parent', app.UIAxes_4);
                imshow(ptcImg, 'parent', app.UIAxes_2);
                imshow(img, 'parent', app.UIAxes);
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.SourceEditField.Value = uigetdir;
            figure(app.UIFigure);
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            app.DestinationEditField.Value = uigetdir;
            figure(app.UIFigure);
        end

        % Value changed function: NoiseDropDown
        function NoiseDropDownValueChanged(app, event)
            value = app.NoiseDropDown.Value;
            disp(value);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if(app.NoiseDropDownLabel == "Gaussian")
                % Adding Gaussian noise
                degradedImg = imnoise(image,'gaussian',0.1,0.05);
                imshow(degradedImg, 'parent', app.UIAxes_2);
            elseif(app.NoiseDropDownLabel == "Salt & Pepper")
                % Adding salt & papper noise
                degradedImg = imnoise(image,'salt & pepper',0.5);
                imshow(degradedImg, 'parent', app.UIAxes_2);
            elseif(app.NoiseDropDownLabel == "poisson")
                degradedImg = imnoise(image,'poisson');
                imshow(degradedImg, 'parent', app.UIAxes_2);

            else
                %degradedImg = img;
            end
        end

        % Value changed function: QECheckBox
        function QECheckBoxValueChanged(app, event)
            value = app.QECheckBox.Value;


        end

        % Value changed function: MTFCheckBox
        function MTFCheckBoxValueChanged(app, event)
            value = app.MTFCheckBox.Value;


        end

        % Value changed function: PTCCheckBox
        function PTCCheckBoxValueChanged(app, event)
            value = app.PTCCheckBox.Value;
            
        end

        % Value changed function: VGCheckBox
        function VGCheckBoxValueChanged(app, event)
            value = app.VGCheckBox.Value;
            
        end

        % Value changed function: TickAllCheckBox
        function TickAllCheckBoxValueChanged(app, event)
            value = app.TickAllCheckBox.Value;
            app.MTFCheckBox.Value = value;
            
            app.PTCCheckBox.Value = value;
            

            app.QECheckBox.Value = value;
          

            app.VGCheckBox.Value = value;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Degraded Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'MTF')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'QE')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'PTC')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Veiling Glare')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.SourceEditFieldLabel = uilabel(app.UIFigure);
            app.SourceEditFieldLabel.Text = 'Source';
            app.SourceEditField = uieditfield(app.UIFigure, 'text');
            app.DestinationEditFieldLabel = uilabel(app.UIFigure);
            app.DestinationEditFieldLabel.Text = 'Destination';
            app.DestinationEditField = uieditfield(app.UIFigure, 'text');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ImageDegradationToolLabel = uilabel(app.UIFigure);
            app.ImageDegradationToolLabel.Text = 'Image Degradation Tool';
            app.BrowseButton_2 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.NoiseDropDownLabel = uilabel(app.UIFigure);
            app.NoiseDropDownLabel.Text = 'Noise';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.NoiseDropDown = uidropdown(app.UIFigure);
            app.NoiseDropDown.Items = {'Select', 'Gaussian', 'Salt & Pepper', 'Poisson'};
            app.NoiseDropDown.Editable = 'on';
            app.NoiseDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseDropDownValueChanged, true);
            app.NoiseDropDown.Value = 'Select';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SelectNoiseCurvePanel = uipanel(app.UIFigure);
            app.SelectNoiseCurvePanel.Title = 'Select Noise Curve';
            app.MTFCheckBox = uicheckbox(app.SelectNoiseCurvePanel);
            app.MTFCheckBox.ValueChangedFcn = createCallbackFcn(app, @MTFCheckBoxValueChanged, true);
            app.MTFCheckBox.Text = 'MTF';
            app.PTCCheckBox = uicheckbox(app.SelectNoiseCurvePanel);
            app.PTCCheckBox.ValueChangedFcn = createCallbackFcn(app, @PTCCheckBoxValueChanged, true);
            app.PTCCheckBox.Text = 'PTC';
            app.QECheckBox = uicheckbox(app.SelectNoiseCurvePanel);
            app.QECheckBox.ValueChangedFcn = createCallbackFcn(app, @QECheckBoxValueChanged, true);
            app.QECheckBox.Text = 'QE';
            app.VGCheckBox = uicheckbox(app.SelectNoiseCurvePanel);
            app.VGCheckBox.ValueChangedFcn = createCallbackFcn(app, @VGCheckBoxValueChanged, true);
            app.VGCheckBox.Text = 'VG';
            app.TickAllCheckBox = uicheckbox(app.SelectNoiseCurvePanel);
            app.TickAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @TickAllCheckBoxValueChanged, true);
            app.TickAllCheckBox.Text = 'Tick All';
            app.GlareIntensityLabel = uilabel(app.UIFigure);
            app.GlareIntensityLabel.Text = 'Glare  Intensity';
            app.GlareIntensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.GlareIntensityEditField.Limits = [0 1];
            app.GlareIntensityEditField.Value = 0.3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Degradation_Tool_l

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end